classdef aplikacija < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        SliderLabel    matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Panel          matlab.ui.container.Panel
        Slider_2Label  matlab.ui.control.Label
        Slider_2       matlab.ui.control.Slider
    end

    properties (Access = private)
        kuteviMjerenja = 1 : 1 : 180;
        pax;
        vrRand = 181 : 1 : 360;
        PolarCoords; % For my polaraxes
        theta = 30;
        r = 50;
    end
    methods (Access = private)
        function results = setUpPolarCoords(app)
            app.kuteviMjerenja = deg2rad(app.kuteviMjerenja);
            app.pax = polaraxes(app.Panel);
            polarplot(app.pax, app.kuteviMjerenja, app.vrRand);
            app.pax.ThetaLim = [0 180];
            app.pax.RLim = [0 400];
            linkdata on;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setUpPolarCoords();
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.vrRand = event.Value;
            polarplot(app.pax, app.theta, app.vrRand);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            app.theta = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 400];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 180];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplikacija

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end