classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElektroakustikaseminarskiradUIFigure  matlab.ui.Figure
        MapaPanel        matlab.ui.container.Panel
        KontrolePanel    matlab.ui.container.Panel
        MapaButton       matlab.ui.control.Button
        RadarButton      matlab.ui.control.Button
        PodaciPanel      matlab.ui.container.Panel
        UdaljenostLabel  matlab.ui.control.Label
        KutLabel         matlab.ui.control.Label
        SliderLabel      matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        Slider_2Label    matlab.ui.control.Label
        Slider_2         matlab.ui.control.Slider
    end

    
    properties (Access = private)
        a
        s
        u
        brzinaZvuka = 343;
        PolarneOsi % Description
        PolarniPlot
        Kut
        Udaljenost
        Vrijednosti = [];
        kutExt = '°';
        udaExt = 'cm';
        testTheta = 1 : 1 : 180;
        testRho = 1 : 1 : 180;
    end
    
    methods (Access = private)
        
        function polarneOsiInit(app)
            app.PolarneOsi = polaraxes(app.MapaPanel);
            app.PolarniPlot = polarplot(app.PolarneOsi, deg2rad(app.testTheta), app.testRho);
            thetalim(app.PolarneOsi, [0 180]); % granice za servo 0 - 180°
            rlim(app.PolarneOsi, [0 400]); % granice za udaljenost 0 - 400 cm
            app.PolarneOsi.GridColorMode = 'manual';
            app.PolarneOsi.Color = 'black';
            app.PolarneOsi.GridColor = [0 1 0];
            app.PolarneOsi.GridAlpha = 0.8;
            app.PolarneOsi.RColor = 'green';
            app.PolarneOsi.ThetaColor = 'green';
            
            
        end
        
        function updatePlot(app)
            app.PolarniPlot = polarplot(app.PolarneOsi,deg2rad(app.Vrijednosti(:,1)), app.Vrijednosti(:,2));
            thetalim(app.PolarneOsi, [0 180]); % granice za servo 0 - 180°
            rlim(app.PolarneOsi, [0 400]); % granice za udaljenost 0 - 400 cm
            app.PolarneOsi.GridColorMode = 'manual';
            app.PolarneOsi.Color = 'black';
            app.PolarneOsi.GridColor = [0 1 0];
            app.PolarneOsi.GridAlpha = 0.8;
            app.PolarneOsi.RColor = 'green';
            app.PolarneOsi.ThetaColor = 'green';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            polarneOsiInit(app);
            app.a = arduino();
            app.s = servo(app.a, 'D10');
            app.u = ultrasonic(app.a, 'D2', 'D3');
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.Kut = num2str(round(event.Value), 3);
            app.KutLabel.Text = app.Kut;
            app.testTheta(round(event.Value)) = round(event.Value);
            updatePlot(app);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            app.Udaljenost = num2str(round(event.Value), 3);
            tempVar = round(event.Value);
            app.testRho(tempVar) = tempVar;
            updatePlot(app);
        end

        % Button pushed function: MapaButton
        function MapaButtonPushed(app, event)
            i = 1;
            for kut = 0 : 1/180 : 1
                writePosition(app.s, kut);
                udaljenost1 = (app.u.readEchoTime() * app.brzinaZvuka) / 2;
                pause(0.25); % pričekaj 500ms
                udaljenost2 = (app.u.readEchoTime() * app.brzinaZvuka) / 2;
                udaljenost = seconds((udaljenost1 + udaljenost2) / 2); % uzmi prosjek 2 mjerenja i spremi u udaljenost
                app.Vrijednosti(i,1) = (i-1); % U prvoj iteraciji postavlja i-ti redak (prvi) prvog stupca na nulu ( kut nula )
                app.Vrijednosti(i,2) = round(udaljenost * 100,2); % pomnoži sa 100 da dobijemo centimetre i zaokruži na 2 decimale
                i = i + 1;
            end
            
            % Rotiraj servo od 180 do 0 stupnjeva
            j = 1;
            for kut = 1 : -1/180 : 0
                writePosition(app.s, kut);
                udaljenost1 = (app.u.readEchoTime() * app.brzinaZvuka) / 2;
                pause(0.25); % pričekaj 500ms
                udaljenost2 = (app.u.readEchoTime()* app.brzinaZvuka) / 2;
                udaljenost = seconds((udaljenost1 + udaljenost2) / 2); % uzmi prosjek 2 mjerenja i spremi u udaljenost
                app.Vrijednosti(i-j, 2) = (app.Vrijednosti(i-j, 2) + round(udaljenost * 100, 2)) / 2; % uzmi prosjek starog i novog mjerenja
                j = j + 1;
            end
            
            % Nacrtaj graf u polarnim koordinatama
            updatePlot(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElektroakustikaseminarskiradUIFigure = uifigure('Visible', 'off');
            app.ElektroakustikaseminarskiradUIFigure.Name = 'Elektroakustika - seminarski rad';
            app.MapaPanel = uipanel(app.ElektroakustikaseminarskiradUIFigure);
            app.MapaPanel.Title = 'Mapa okruženja';
            app.KontrolePanel = uipanel(app.ElektroakustikaseminarskiradUIFigure);
            app.KontrolePanel.Title = 'Kontrole';
            app.MapaButton = uibutton(app.KontrolePanel, 'push');
            app.MapaButton.ButtonPushedFcn = createCallbackFcn(app, @MapaButtonPushed, true);
            app.MapaButton.Text = 'Napravi mapu';
            app.RadarButton = uibutton(app.KontrolePanel, 'push');
            app.RadarButton.Enable = 'off';
            app.RadarButton.Text = 'Radarski način';
            app.PodaciPanel = uipanel(app.ElektroakustikaseminarskiradUIFigure);
            app.PodaciPanel.Title = 'Podaci';
            app.UdaljenostLabel = uilabel(app.PodaciPanel);
            app.UdaljenostLabel.Text = 'Udaljenost';
            app.KutLabel = uilabel(app.PodaciPanel);
            app.KutLabel.Text = 'Kut';
            app.SliderLabel = uilabel(app.PodaciPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.PodaciPanel);
            app.Slider.Limits = [1 180];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 90;
            app.Slider_2Label = uilabel(app.PodaciPanel);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.PodaciPanel);
            app.Slider_2.Limits = [1 400];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 100;
            % Show the figure after all components are created
            app.ElektroakustikaseminarskiradUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElektroakustikaseminarskiradUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElektroakustikaseminarskiradUIFigure)
        end
    end
end